classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEProjectAliNaghilooUIFigure   matlab.ui.Figure
        SensivityGraphClickButton      matlab.ui.control.Button
        EditField_Range_2              matlab.ui.control.NumericEditField
        EditField_Range                matlab.ui.control.NumericEditField
        mSpinner_2                     matlab.ui.control.Spinner
        mSpinner_2Label                matlab.ui.control.Label
        ButtonTX                       matlab.ui.control.Button
        rdEditField                    matlab.ui.control.NumericEditField
        rdEditFieldLabel               matlab.ui.control.Label
        ndEditField                    matlab.ui.control.NumericEditField
        ndEditFieldLabel               matlab.ui.control.Label
        stEditField                    matlab.ui.control.NumericEditField
        stEditFieldLabel               matlab.ui.control.Label
        range                          matlab.ui.control.RangeSlider
        TAXSectionsSliderLabel         matlab.ui.control.Label
        EditField5                     matlab.ui.control.NumericEditField
        CashFlowPerPeriodSlider5       matlab.ui.control.Slider
        salvagevalueLabel              matlab.ui.control.Label
        EditField4                     matlab.ui.control.NumericEditField
        CashFlowPerPeriodSlider4       matlab.ui.control.Slider
        UniformGradientGLabel          matlab.ui.control.Label
        CalculateDmBVmButton           matlab.ui.control.Button
        mSpinner                       matlab.ui.control.Spinner
        mSpinnerLabel                  matlab.ui.control.Label
        EditField3                     matlab.ui.control.NumericEditField
        CashFlowPerPeriodSlider3       matlab.ui.control.Slider
        ResultTextAreaLabel            matlab.ui.control.Label
        MARRLabel                      matlab.ui.control.Label
        DepreciationMethodButtonGroup  matlab.ui.container.ButtonGroup
        DBButton                       matlab.ui.control.ToggleButton
        SOYDButton                     matlab.ui.control.ToggleButton
        SLButton                       matlab.ui.control.ToggleButton
        ResultTextArea                 matlab.ui.control.TextArea
        EditField2                     matlab.ui.control.NumericEditField
        CashFlowPerPeriodSlider2       matlab.ui.control.Slider
        Time0InvestmentLabel           matlab.ui.control.Label
        EditField1                     matlab.ui.control.NumericEditField
        CashFlowPerPeriodSlider1       matlab.ui.control.Slider
        NumberOfPeriodLabel            matlab.ui.control.Label
        EditField0                     matlab.ui.control.NumericEditField
        CashFlowPerPeriodSlider0       matlab.ui.control.Slider
        AnnualRevenueLabel             matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        index = ' ';

        initial_investment = zeros(1,10);
        A ;
        G ;
        years ;
        MARR ;
        salvage_value;

        NPW = ones(1,10);
        NFW = ones(1,10);
        NEUA = ones(1,10);

%---
        DMethod = 1;
        m = 1 ;

        D;
        BV;

 %-----
        L=50;
        U=175;
        L1=0.1;
        L2=0.2;
        L3=0.3;
        m2 = 1;

        eachyear = 0;

        flag=0;


    end
    
    methods (Access = private)
        
        function  [NPW,NEUA,NFW]=calc(app,initial_investment, A, G, MARR, years, salvage_value)
            NPW = -initial_investment;
            for i = 1:years
                NPW = NPW + (A + G .* (i - 1)) ./ ((1 + MARR) .^ i);
            end
            NPW = NPW + salvage_value ./ ((1 + MARR) .^ years);
             
            NEUA = NPW * ((1 + MARR) ^ years - 1) / (MARR * (1 + MARR) ^ years);

            NFW = NPW * (1 + MARR) ^ years;
         
            
        end
        
        function Cashfplot(app,years,A,G,init_investment,SV)
        
        CFlowAmounts = zeros(1,years);
        CFlowDates = ones(1,years);

        for i = 0:years
            CFlowDates(i+1) = i;
        end
        CFlowAmounts(1) = init_investment;
        CFlowAmounts(2) = A;
        CFlowAmounts(years+1) = A+G+SV;
        for i = 3:years
            CFlowAmounts(i) = A + (i-2).*G;
        end
                % Plot the cash flow diagram
        cla(app.UIAxes)        
        cfplot(app.UIAxes,CFlowDates, CFlowAmounts,'ShowAmnt','all','datespacing',0.5);
        
        end 

        function [D,BV] = Depreciation(app,P,SV,n,m)
            if(app.DMethod == 1)
                D = (P-SV)./n;
                BV = P-m.*D;
            elseif(app.DMethod == 2)
                D = ((n-m+1)./(0.5.*n.*(n+1))).*(P-SV);
                BV = P - ((m.*(n-0.5.*m+0.5)./(0.5.*n.*(n+1)))).*(P-SV);
            else
                d = 1 - (SV./P).^(1./n);
                D = d.*P.*(1-d).^(m-1);
                BV = P.*(1-d).^m;
            end
        end

                function CFlowAmounts = Cashf(app,years,A,G,init_investment,SV)
        
        CFlowAmounts = zeros(1,years);
        CFlowDates = ones(1,years);

        for i = 0:years
            CFlowDates(i+1) = i;
        end
        CFlowAmounts(1) = init_investment;
        CFlowAmounts(2) = A;
        CFlowAmounts(years+1) = A+G+SV;
        for i = 3:years
            CFlowAmounts(i) = A + (i-2).*G;
        end
                % Plot the cash flow diagram
        % cla(app.UIAxes)        
        % cfplot(app.UIAxes,CFlowDates, CFlowAmounts,'ShowAmnt','all','datespacing',0.5);
        
        end


        function results = TXX(app,L,U,L1,L2,L3,IT)
            if(IT < 0); results=0;
            elseif(IT <= L); results=L1.*IT;
            elseif(IT <= U); results=L.*L1 + (IT-L).*L2;
            else; results=L.*L1 + (U-L).*L2 + (IT-U).*L3; 
            % elseif(U < IT); results=L.*L1 + (U-L).*L2 + (IT-U).*L3;
            end
            
        end
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: CashFlowPerPeriodSlider0
        function CashFlowPerPeriodSlider0ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField0.Value = changingValue;
                    app.initial_investment = app.EditField2.Value;
        app.A = app.EditField0.Value;
        app.G = app.EditField4.Value;
        app.years = app.EditField1.Value;
        app.MARR = app.EditField3.Value;
        app.salvage_value = app.EditField5.Value;


                [app.NPW,app.NEUA,app.NFW]=calc(app,app.initial_investment, app.A, app.G, app.MARR, app.years, app.salvage_value);
        app.ResultTextArea.Value = {['Project Properties',num2str(app.index)]; ['NPW = ',num2str(app.NPW)];['NEUA = ',num2str(app.NEUA)]; ['NFW = ',num2str(app.NFW)]};

        Cashfplot(app,app.years,app.A,app.G,app.initial_investment,app.salvage_value)
        end

        % Value changing function: CashFlowPerPeriodSlider1
        function CashFlowPerPeriodSlider1ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField1.Value = floor(changingValue);
                   app.initial_investment = app.EditField2.Value;
        app.A = app.EditField0.Value;
        app.G = app.EditField4.Value;
        app.years = app.EditField1.Value;
        app.MARR = app.EditField3.Value;
        app.salvage_value = app.EditField5.Value;
        app.mSpinner.Limits = [1 app.years];
        app.mSpinner_2.Limits = [1 app.years];
               [app.NPW,app.NEUA,app.NFW]=calc(app,app.initial_investment, app.A, app.G, app.MARR, app.years, app.salvage_value);
        app.ResultTextArea.Value = {['Project Properties',num2str(app.index)]; ['NPW = ',num2str(app.NPW)];['NEUA = ',num2str(app.NEUA)]; ['NFW = ',num2str(app.NFW)]};
        Cashfplot(app,app.years,app.A,app.G,app.initial_investment,app.salvage_value)
        end

        % Value changing function: CashFlowPerPeriodSlider2
        function CashFlowPerPeriodSlider2ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField2.Value = changingValue;
                    app.initial_investment = app.EditField2.Value;
        app.A = app.EditField0.Value;
        app.G = app.EditField4.Value;
        app.years = app.EditField1.Value;
        app.MARR = app.EditField3.Value;
        app.salvage_value = app.EditField5.Value;
if(abs(app.initial_investment) > app.salvage_value && 0 > app.initial_investment && app.salvage_value > 0);app.CalculateDmBVmButton.Enable = 'on';app.ButtonTX.Enable = 'on'; else;app.CalculateDmBVmButton.Enable = 'off';app.ButtonTX.Enable = 'off';end
                [app.NPW,app.NEUA,app.NFW]=calc(app,app.initial_investment, app.A, app.G, app.MARR, app.years, app.salvage_value);
        app.ResultTextArea.Value = {['Project Properties',num2str(app.index)]; ['NPW = ',num2str(app.NPW)];['NEUA = ',num2str(app.NEUA)]; ['NFW = ',num2str(app.NFW)]};
        Cashfplot(app,app.years,app.A,app.G,app.initial_investment,app.salvage_value)
        end

        % Value changing function: CashFlowPerPeriodSlider3
        function CashFlowPerPeriodSlider3ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField3.Value = changingValue;
                    app.initial_investment = app.EditField2.Value;
        app.A = app.EditField0.Value;
        app.G = app.EditField4.Value;
        app.years = app.EditField1.Value;
        app.MARR = app.EditField3.Value;
        app.salvage_value = app.EditField5.Value;

                [app.NPW,app.NEUA,app.NFW]=calc(app,app.initial_investment, app.A, app.G, app.MARR, app.years, app.salvage_value);
        app.ResultTextArea.Value = {['Project Properties',num2str(app.index)]; ['NPW = ',num2str(app.NPW)];['NEUA = ',num2str(app.NEUA)]; ['NFW = ',num2str(app.NFW)]};
        Cashfplot(app,app.years,app.A,app.G,app.initial_investment,app.salvage_value)
        end

        % Value changing function: CashFlowPerPeriodSlider4
        function CashFlowPerPeriodSlider4ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField4.Value = changingValue;
                    app.initial_investment = app.EditField2.Value;
        app.A = app.EditField0.Value;
        app.G = app.EditField4.Value;
        app.years = app.EditField1.Value;
        app.MARR = app.EditField3.Value;
        app.salvage_value = app.EditField5.Value;


                [app.NPW,app.NEUA,app.NFW]=calc(app,app.initial_investment, app.A, app.G, app.MARR, app.years, app.salvage_value);
        app.ResultTextArea.Value = {['Project Properties',num2str(app.index)]; ['NPW = ',num2str(app.NPW)];['NEUA = ',num2str(app.NEUA)]; ['NFW = ',num2str(app.NFW)]};
        Cashfplot(app,app.years,app.A,app.G,app.initial_investment,app.salvage_value)
        end

        % Value changing function: CashFlowPerPeriodSlider5
        function CashFlowPerPeriodSlider5ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField5.Value = changingValue;
                    app.initial_investment = app.EditField2.Value;
        app.A = app.EditField0.Value;
        app.G = app.EditField4.Value;
        app.years = app.EditField1.Value;
        app.MARR = app.EditField3.Value;
        app.salvage_value = app.EditField5.Value;
if(abs(app.initial_investment) > app.salvage_value && 0 > app.initial_investment && app.salvage_value > 0);app.CalculateDmBVmButton.Enable = 'on';app.ButtonTX.Enable = 'on'; else;app.CalculateDmBVmButton.Enable = 'off';app.ButtonTX.Enable = 'off';end
                [app.NPW,app.NEUA,app.NFW]=calc(app,app.initial_investment, app.A, app.G, app.MARR, app.years, app.salvage_value);
        app.ResultTextArea.Value = {['Project Properties',num2str(app.index)]; ['NPW = ',num2str(app.NPW)];['NEUA = ',num2str(app.NEUA)]; ['NFW = ',num2str(app.NFW)]};
        Cashfplot(app,app.years,app.A,app.G,app.initial_investment,app.salvage_value)
        end

        % Value changed function: EditField0
        function EditField0ValueChanged(app, event)
            value = app.EditField0.Value;
            app.CashFlowPerPeriodSlider0.Value = value;
        app.initial_investment = app.EditField2.Value;
        app.A = app.EditField0.Value;
        app.G = app.EditField4.Value;
        app.years = app.EditField1.Value;
        app.MARR = app.EditField3.Value;
        app.salvage_value = app.EditField5.Value;


                [app.NPW,app.NEUA,app.NFW]=calc(app,app.initial_investment, app.A, app.G, app.MARR, app.years, app.salvage_value);
        app.ResultTextArea.Value = {['Project Properties',num2str(app.index)]; ['NPW = ',num2str(app.NPW)];['NEUA = ',num2str(app.NEUA)]; ['NFW = ',num2str(app.NFW)]};
        Cashfplot(app,app.years,app.A,app.G,app.initial_investment,app.salvage_value)
        end

        % Value changed function: EditField1
        function EditField1ValueChanged(app, event)
            value = app.EditField1.Value;
            app.CashFlowPerPeriodSlider1.Value = value;
        app.initial_investment = app.EditField2.Value;
        app.A = app.EditField0.Value;
        app.G = app.EditField4.Value;
        app.years = app.EditField1.Value;
        app.MARR = app.EditField3.Value;
        app.salvage_value = app.EditField5.Value;
app.mSpinner.Limits = [1 app.years];
app.mSpinner_2.Limits = [1 app.years];
                [app.NPW,app.NEUA,app.NFW]=calc(app,app.initial_investment, app.A, app.G, app.MARR, app.years, app.salvage_value);
        app.ResultTextArea.Value = {['Project Properties',num2str(app.index)]; ['NPW = ',num2str(app.NPW)];['NEUA = ',num2str(app.NEUA)]; ['NFW = ',num2str(app.NFW)]};
        Cashfplot(app,app.years,app.A,app.G,app.initial_investment,app.salvage_value)
        end

        % Value changed function: EditField2
        function EditField2ValueChanged(app, event)
            value = app.EditField2.Value;
            app.CashFlowPerPeriodSlider2.Value = value;
        app.initial_investment = app.EditField2.Value;
        app.A = app.EditField0.Value;
        app.G = app.EditField4.Value;
        app.years = app.EditField1.Value;
        app.MARR = app.EditField3.Value;
        app.salvage_value = app.EditField5.Value;
if(abs(app.initial_investment) > app.salvage_value && 0 > app.initial_investment && app.salvage_value > 0);app.CalculateDmBVmButton.Enable = 'on';app.ButtonTX.Enable = 'on'; else;app.CalculateDmBVmButton.Enable = 'off';app.ButtonTX.Enable = 'off';end
               [app.NPW,app.NEUA,app.NFW]=calc(app,app.initial_investment, app.A, app.G, app.MARR, app.years, app.salvage_value);
        app.ResultTextArea.Value = {['Project Properties',num2str(app.index)]; ['NPW = ',num2str(app.NPW)];['NEUA = ',num2str(app.NEUA)]; ['NFW = ',num2str(app.NFW)]};
        Cashfplot(app,app.years,app.A,app.G,app.initial_investment,app.salvage_value)
        end

        % Value changed function: EditField3
        function EditField3ValueChanged(app, event)
            value = app.EditField3.Value;
            app.CashFlowPerPeriodSlider3.Value = min(value,1);
        app.initial_investment = app.EditField2.Value;
        app.A = app.EditField0.Value;
        app.G = app.EditField4.Value;
        app.years = app.EditField1.Value;
        app.MARR = app.EditField3.Value;
        app.salvage_value = app.EditField5.Value;

        
               [app.NPW,app.NEUA,app.NFW]=calc(app,app.initial_investment, app.A, app.G, app.MARR, app.years, app.salvage_value);
        app.ResultTextArea.Value = {['Project Properties',num2str(app.index)]; ['NPW = ',num2str(app.NPW)];['NEUA = ',num2str(app.NEUA)]; ['NFW = ',num2str(app.NFW)]};
        Cashfplot(app,app.years,app.A,app.G,app.initial_investment,app.salvage_value)
        end

        % Value changed function: EditField4
        function EditField4ValueChanged(app, event)
            value = app.EditField4.Value;
            app.CashFlowPerPeriodSlider4.Value = value;
        app.initial_investment = app.EditField2.Value;
        app.A = app.EditField0.Value;
        app.G = app.EditField4.Value;
        app.years = app.EditField1.Value;
        app.MARR = app.EditField3.Value;
        app.salvage_value = app.EditField5.Value;


                [app.NPW,app.NEUA,app.NFW]=calc(app,app.initial_investment, app.A, app.G, app.MARR, app.years, app.salvage_value);
        app.ResultTextArea.Value = {['Project Properties',num2str(app.index)]; ['NPW = ',num2str(app.NPW)];['NEUA = ',num2str(app.NEUA)]; ['NFW = ',num2str(app.NFW)]};
        Cashfplot(app,app.years,app.A,app.G,app.initial_investment,app.salvage_value)
        end

        % Value changed function: EditField5
        function EditField5ValueChanged(app, event)
            value = app.EditField5.Value;
            app.CashFlowPerPeriodSlider5.Value = value;
        app.initial_investment = app.EditField2.Value;
        app.A = app.EditField0.Value;
        app.G = app.EditField4.Value;
        app.years = app.EditField1.Value;
        app.MARR = app.EditField3.Value;
        app.salvage_value = app.EditField5.Value;
if(abs(app.initial_investment) > app.salvage_value && 0 > app.initial_investment && app.salvage_value > 0);app.CalculateDmBVmButton.Enable = 'on';app.ButtonTX.Enable = 'on'; else;app.CalculateDmBVmButton.Enable = 'off';app.ButtonTX.Enable = 'off';end
               [app.NPW,app.NEUA,app.NFW]=calc(app,app.initial_investment, app.A, app.G, app.MARR, app.years, app.salvage_value);
        app.ResultTextArea.Value = {['Project Properties',num2str(app.index)]; ['NPW = ',num2str(app.NPW)];['NEUA = ',num2str(app.NEUA)]; ['NFW = ',num2str(app.NFW)]};
        Cashfplot(app,app.years,app.A,app.G,app.initial_investment,app.salvage_value)
        end

        % Callback function
        function SelectDropDownValueChanged(app, event)
        %     value = app.SelectDropDown.Value;
        %     switch value
        %         case 'Project 1'
        %             app.index = 1;
        %         case 'Project 2'
        %             app.index = 2;
        %         case 'Project 3'
        %             app.index = 3;
        %         case 'Project 4'
        %             app.index = 4;
        %         case 'Project 5'
        %             app.index = 5;
        %         case 'Project 6'
        %             app.index = 6;
        %         case 'Project 7'
        %             app.index = 7;                                                                            
        %         case 'Project 8'
        %             app.index = 8;
        %         case 'Project 9'
        %             app.index = 9; 
        %         case 'Project 10'
        %             app.index = 10;
        %     end
        % 
        % app.initial_investment = app.EditField2.Value;
        % app.A = app.EditField0.Value;
        % app.G = app.EditField4.Value;
        % app.years = app.EditField1.Value;
        % app.MARR = app.EditField3.Value;
        % app.salvage_value = app.EditField5.Value;
        % 
        % app.ResultTextArea.Value = {['Project',num2str(app.index)]; ['NPW = ',num2str(app.NPW)];['NEUA = ',num2str(app.NEUA)]; ['NFW = ',num2str(app.NFW)]};
        % % [app.NPW,app.NEUA,app.NFW]=calc(app,app.initial_investment, app.A, app.G, app.MARR, app.years, app.salvage_value);
        % Cashfplot(app,app.years,app.A,app.G,app.initial_investment,app.salvage_value) 
       
        end

        % Selection changed function: DepreciationMethodButtonGroup
        function DepreciationMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.DepreciationMethodButtonGroup.SelectedObject;
            if (selectedButton == app.SLButton); app.DMethod=1; elseif(selectedButton == app.SOYDButton); app.DMethod=2;else;app.DMethod=3;end
        end

        % Value changed function: mSpinner
        function mSpinnerValueChanged(app, event)
            value = app.mSpinner.Value;
            app.m = value;
        end

        % Button pushed function: CalculateDmBVmButton
        function CalculateDmBVmButtonPushed(app, event)
            [app.D,app.BV] = Depreciation(app,abs(app.initial_investment),app.salvage_value,app.years,app.m);
            app.ResultTextArea.Value = {['Project Properties',num2str(app.index)]; ['NPW = ',num2str(app.NPW)];['NEUA = ',num2str(app.NEUA)]; ['NFW = ',num2str(app.NFW)];['@ m = ',num2str(app.m),' ==>',' D = ',num2str(app.D),'    BV = ',num2str(app.BV)]};
        end

        % Value changing function: range
        function rangeValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_Range.Value = changingValue(1);
            app.EditField_Range_2.Value = changingValue(2);
            app.L = changingValue(1);
            app.U = changingValue(2);
        end

        % Value changed function: EditField_Range
        function EditField_RangeValueChanged(app, event)
            value = app.EditField_Range.Value;
            app.range.Value(1) = value;
            app.L = value;
        end

        % Value changed function: EditField_Range_2
        function EditField_Range_2ValueChanged(app, event)
            value = app.EditField_Range_2.Value;
            app.range.Value(2) = value;
            app.U = value;
        end

        % Value changed function: stEditField
        function stEditFieldValueChanged(app, event)
            value = app.stEditField.Value;
            app.L1=value;
        end

        % Value changed function: ndEditField
        function ndEditFieldValueChanged(app, event)
            value = app.ndEditField.Value;
             app.L2=value;
        end

        % Value changed function: rdEditField
        function rdEditFieldValueChanged(app, event)
            value = app.rdEditField.Value;
             app.L3=value;
        end

        % Value changed function: mSpinner_2
        function mSpinner_2ValueChanged(app, event)
            value = app.mSpinner_2.Value;
            app.m2 = value;
        end

        % Button pushed function: ButtonTX
        function ButtonTXPushed(app, event)
            app.eachyear = Cashf(app,app.years,app.A,app.G,app.initial_investment,app.salvage_value);
            app.eachyear(app.years+1) = app.eachyear(app.years+1)-app.salvage_value;
            [app.D,app.BV] = Depreciation(app,abs(app.initial_investment),app.salvage_value,app.years,app.m2);
            app.eachyear(app.m2+1) = app.eachyear(app.m2+1) - app.D;
            results = TXX(app,app.L,app.U,app.L1,app.L2,app.L3,app.eachyear(app.m2+1));

            app.ResultTextArea.Value = {['Project Properties',num2str(app.index)]; ['NPW = ',num2str(app.NPW)];['NEUA = ',num2str(app.NEUA)]; ['NFW = ',num2str(app.NFW)];['@ m = ',num2str(app.m2),' ==>',' TAX = ',num2str(results)]};


        end

        % Button pushed function: SensivityGraphClickButton
        function SensivityGraphClickButtonPushed(app, event)
% P/A Factor:
function r = PA(i, n);r = ((1+i).^n - 1)./(i.*(1+i).^n);end

% P/G Factor:
function r = PG(i, n);r = (((1 + i).^n - i.*n - 1) ./ (i.^2 .* (1 + i).^n));end

% P/F Factor:
function r = PF(i, n);r = 1./(1 + i).^n;end

% NPW function:
            function r = NPW_f(P, G, i, A, SV, n)
    r = P + A.*PA(i,n) + G.*PG(i,n) + SV.*PF(i, n);
end

Percentage = [-50:10:+50];
figure(1);

% Initial values
P_base = app.initial_investment+0.00000001;
i_base = app.MARR+0.000000001 ;
G_base = app.G+0.000000001;

A_base = app.A+0.000000001;
SV_base = app.salvage_value+0.000000001;
n_base = app.years+0.000000001;

% Plot for varying P
P_varied = P_base * (1 + Percentage/100);
NPW_P = NPW_f(P_varied, G_base, i_base, A_base, SV_base, n_base);
plot(Percentage, NPW_P, 'LineWidth', 1.2);
hold on;

% Plot for varying i
i_varied = i_base * (1 + Percentage/100);
NPW_i = NPW_f(P_base, G_base, i_varied, A_base, SV_base, n_base);
plot(Percentage, NPW_i, 'LineWidth', 1.2);
hold on;

% Plot for varying G
G_varied = G_base * (1 + Percentage/100);
NPW_G = NPW_f(P_base, G_varied, i_base, A_base, SV_base, n_base);
plot(Percentage, NPW_G, 'LineWidth', 1.2);
hold on;

% Plot for varying A
A_varied = A_base * (1 + Percentage/100);
NPW_A = NPW_f(P_base, G_base, i_base, A_varied, SV_base, n_base);
plot(Percentage, NPW_A, 'LineWidth', 1.2);
hold on;

% Plot for varying SV
SV_varied = SV_base * (1 + Percentage/100);
NPW_SV = NPW_f(P_base, G_base, i_base, A_base, SV_varied, n_base);
plot(Percentage, NPW_SV, 'LineWidth', 1.2);
hold on;

% Plot for varying n
n_varied = n_base * (1 + Percentage/100);
NPW_n = NPW_f(P_base, G_base, i_base, A_base, SV_base, n_varied);
plot(Percentage, NPW_n, 'LineWidth', 1.2);
hold on;

ylabel('NPW', 'FontWeight', 'bold');
xlabel('Changing Percentage', 'FontWeight', 'bold');
xtickformat("percentage");
legend({'P','i','G','A','SV','n'}, 'Location', 'best');
grid on; grid minor;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EEProjectAliNaghilooUIFigure = uifigure('Visible', 'off');
            app.EEProjectAliNaghilooUIFigure.Name = 'EE Project @ Ali Naghiloo';
            app.EEProjectAliNaghilooUIFigure.Icon = fullfile(pathToMLAPP, 'Engineering Economics.png');
            app.UIAxes = uiaxes(app.EEProjectAliNaghilooUIFigure);
            title(app.UIAxes, 'Cash Flow')
            xlabel(app.UIAxes, 'Years')
            ylabel(app.UIAxes, 'Money')
            app.Image = uiimage(app.EEProjectAliNaghilooUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Engineering Economics.png');
            app.Image2 = uiimage(app.EEProjectAliNaghilooUIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'modern-laptop-mockup-template-46i8fst48bo1rb5w-46i8fst48bo1rb5w.png');
            app.AnnualRevenueLabel = uilabel(app.EEProjectAliNaghilooUIFigure);
            app.AnnualRevenueLabel.Text = 'Annual Revenue (A)';
            app.CashFlowPerPeriodSlider0 = uislider(app.EEProjectAliNaghilooUIFigure);
            app.CashFlowPerPeriodSlider0.Limits = [-250 250];
            app.CashFlowPerPeriodSlider0.ValueChangingFcn = createCallbackFcn(app, @CashFlowPerPeriodSlider0ValueChanging, true);
            app.CashFlowPerPeriodSlider0.Tooltip = {'Slide me !'};
            app.EditField0 = uieditfield(app.EEProjectAliNaghilooUIFigure, 'numeric');
            app.EditField0.ValueDisplayFormat = '%.0f';
            app.EditField0.ValueChangedFcn = createCallbackFcn(app, @EditField0ValueChanged, true);
            app.NumberOfPeriodLabel = uilabel(app.EEProjectAliNaghilooUIFigure);
            app.NumberOfPeriodLabel.Text = 'Number of Period (n)';
            app.CashFlowPerPeriodSlider1 = uislider(app.EEProjectAliNaghilooUIFigure);
            app.CashFlowPerPeriodSlider1.Limits = [1 40];
            app.CashFlowPerPeriodSlider1.ValueChangingFcn = createCallbackFcn(app, @CashFlowPerPeriodSlider1ValueChanging, true);
            app.CashFlowPerPeriodSlider1.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40];
            app.CashFlowPerPeriodSlider1.Tooltip = {'Slide me !'};
            app.CashFlowPerPeriodSlider1.Value = 1;
            app.EditField1 = uieditfield(app.EEProjectAliNaghilooUIFigure, 'numeric');
            app.EditField1.ValueDisplayFormat = '%.2f';
            app.EditField1.ValueChangedFcn = createCallbackFcn(app, @EditField1ValueChanged, true);
            app.EditField1.Tooltip = {'Type or Slide !'};
            app.EditField1.Value = 1;
            app.Time0InvestmentLabel = uilabel(app.EEProjectAliNaghilooUIFigure);
            app.Time0InvestmentLabel.Text = 'Time 0 Investment';
            app.CashFlowPerPeriodSlider2 = uislider(app.EEProjectAliNaghilooUIFigure);
            app.CashFlowPerPeriodSlider2.Limits = [-250 250];
            app.CashFlowPerPeriodSlider2.ValueChangingFcn = createCallbackFcn(app, @CashFlowPerPeriodSlider2ValueChanging, true);
            app.CashFlowPerPeriodSlider2.Tooltip = {'Slide me !'};
            app.EditField2 = uieditfield(app.EEProjectAliNaghilooUIFigure, 'numeric');
            app.EditField2.ValueDisplayFormat = '%.2f';
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.EditField2.Tooltip = {'Type or Slide !'};
            app.ResultTextArea = uitextarea(app.EEProjectAliNaghilooUIFigure);
            app.ResultTextArea.Value = {'Welcome to Naghiloo''s App!'};
            app.DepreciationMethodButtonGroup = uibuttongroup(app.EEProjectAliNaghilooUIFigure);
            app.DepreciationMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DepreciationMethodButtonGroupSelectionChanged, true);
            app.DepreciationMethodButtonGroup.Title = 'Depreciation Method:';
            app.SLButton = uitogglebutton(app.DepreciationMethodButtonGroup);
            app.SLButton.Tooltip = {'Strait Line'};
            app.SLButton.Text = 'SL';
            app.SLButton.Value = true;
            app.SOYDButton = uitogglebutton(app.DepreciationMethodButtonGroup);
            app.SOYDButton.Tooltip = {'Some Of The Years Digit'};
            app.SOYDButton.Text = 'SOYD';
            app.DBButton = uitogglebutton(app.DepreciationMethodButtonGroup);
            app.DBButton.Tooltip = {'Declining Balance'};
            app.DBButton.Text = 'DB';
            app.MARRLabel = uilabel(app.EEProjectAliNaghilooUIFigure);
            app.MARRLabel.Text = 'MARR';
            app.ResultTextAreaLabel = uilabel(app.EEProjectAliNaghilooUIFigure);
            app.ResultTextAreaLabel.Text = 'Result:';
            app.CashFlowPerPeriodSlider3 = uislider(app.EEProjectAliNaghilooUIFigure);
            app.CashFlowPerPeriodSlider3.Limits = [0 1];
            app.CashFlowPerPeriodSlider3.ValueChangingFcn = createCallbackFcn(app, @CashFlowPerPeriodSlider3ValueChanging, true);
            app.CashFlowPerPeriodSlider3.Tooltip = {'Slide me !'};
            app.EditField3 = uieditfield(app.EEProjectAliNaghilooUIFigure, 'numeric');
            app.EditField3.Limits = [0 1];
            app.EditField3.ValueDisplayFormat = '%.2f';
            app.EditField3.ValueChangedFcn = createCallbackFcn(app, @EditField3ValueChanged, true);
            app.EditField3.Tooltip = {'Type or Slide !'};
            app.mSpinnerLabel = uilabel(app.EEProjectAliNaghilooUIFigure);
            app.mSpinnerLabel.Text = 'm:';
            app.mSpinner = uispinner(app.EEProjectAliNaghilooUIFigure);
            app.mSpinner.Limits = [1 1];
            app.mSpinner.ValueDisplayFormat = '%.0f';
            app.mSpinner.ValueChangedFcn = createCallbackFcn(app, @mSpinnerValueChanged, true);
            app.mSpinner.Tooltip = {'The year you want to know'; 'Depreciation and Book Value!'};
            app.mSpinner.Value = 1;
            app.CalculateDmBVmButton = uibutton(app.EEProjectAliNaghilooUIFigure, 'push');
            app.CalculateDmBVmButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDmBVmButtonPushed, true);
            app.CalculateDmBVmButton.Enable = 'off';
            app.CalculateDmBVmButton.Text = {'Calculate'; 'D(m) , BV(m)'};
            app.UniformGradientGLabel = uilabel(app.EEProjectAliNaghilooUIFigure);
            app.UniformGradientGLabel.Text = 'Uniform Gradient (G)';
            app.CashFlowPerPeriodSlider4 = uislider(app.EEProjectAliNaghilooUIFigure);
            app.CashFlowPerPeriodSlider4.Limits = [-50 50];
            app.CashFlowPerPeriodSlider4.ValueChangingFcn = createCallbackFcn(app, @CashFlowPerPeriodSlider4ValueChanging, true);
            app.CashFlowPerPeriodSlider4.Tooltip = {'Slide me !'};
            app.EditField4 = uieditfield(app.EEProjectAliNaghilooUIFigure, 'numeric');
            app.EditField4.ValueDisplayFormat = '%.2f';
            app.EditField4.ValueChangedFcn = createCallbackFcn(app, @EditField4ValueChanged, true);
            app.EditField4.Tooltip = {'Type or Slide !'};
            app.salvagevalueLabel = uilabel(app.EEProjectAliNaghilooUIFigure);
            app.salvagevalueLabel.Text = 'salvage value';
            app.CashFlowPerPeriodSlider5 = uislider(app.EEProjectAliNaghilooUIFigure);
            app.CashFlowPerPeriodSlider5.Limits = [0 500];
            app.CashFlowPerPeriodSlider5.ValueChangingFcn = createCallbackFcn(app, @CashFlowPerPeriodSlider5ValueChanging, true);
            app.CashFlowPerPeriodSlider5.Tooltip = {'Slide me !'};
            app.EditField5 = uieditfield(app.EEProjectAliNaghilooUIFigure, 'numeric');
            app.EditField5.ValueDisplayFormat = '%.2f';
            app.EditField5.ValueChangedFcn = createCallbackFcn(app, @EditField5ValueChanged, true);
            app.EditField5.Tooltip = {'Type or Slide !'};
            app.TAXSectionsSliderLabel = uilabel(app.EEProjectAliNaghilooUIFigure);
            app.TAXSectionsSliderLabel.Text = 'TAX Sections:';
            app.range = uislider(app.EEProjectAliNaghilooUIFigure, 'range');
            app.range.Limits = [0 250];
            app.range.MajorTicks = [0 25 50 75 100 125 150 175 200 225 250];
            app.range.MajorTickLabels = {'0', '25', '50', '75', '100', '125', '150', '175', '200', '225', '250'};
            app.range.ValueChangingFcn = createCallbackFcn(app, @rangeValueChanging, true);
            app.range.Step = 1;
            app.range.Value = [50 175];
            app.stEditFieldLabel = uilabel(app.EEProjectAliNaghilooUIFigure);
            app.stEditFieldLabel.Text = '1st:';
            app.stEditField = uieditfield(app.EEProjectAliNaghilooUIFigure, 'numeric');
            app.stEditField.Limits = [0 1];
            app.stEditField.ValueDisplayFormat = '%.3f';
            app.stEditField.ValueChangedFcn = createCallbackFcn(app, @stEditFieldValueChanged, true);
            app.stEditField.Value = 0.1;
            app.ndEditFieldLabel = uilabel(app.EEProjectAliNaghilooUIFigure);
            app.ndEditFieldLabel.Text = '2nd:';
            app.ndEditField = uieditfield(app.EEProjectAliNaghilooUIFigure, 'numeric');
            app.ndEditField.Limits = [0 1];
            app.ndEditField.ValueDisplayFormat = '%.3f';
            app.ndEditField.ValueChangedFcn = createCallbackFcn(app, @ndEditFieldValueChanged, true);
            app.ndEditField.Value = 0.2;
            app.rdEditFieldLabel = uilabel(app.EEProjectAliNaghilooUIFigure);
            app.rdEditFieldLabel.Text = '3rd:';
            app.rdEditField = uieditfield(app.EEProjectAliNaghilooUIFigure, 'numeric');
            app.rdEditField.Limits = [0 1];
            app.rdEditField.ValueDisplayFormat = '%.3f';
            app.rdEditField.ValueChangedFcn = createCallbackFcn(app, @rdEditFieldValueChanged, true);
            app.rdEditField.Value = 0.3;
            app.ButtonTX = uibutton(app.EEProjectAliNaghilooUIFigure, 'push');
            app.ButtonTX.ButtonPushedFcn = createCallbackFcn(app, @ButtonTXPushed, true);
            app.ButtonTX.Text = {'Calculate'; 'TAX(m)'};
            app.mSpinner_2Label = uilabel(app.EEProjectAliNaghilooUIFigure);
            app.mSpinner_2Label.Text = 'm:';
            app.mSpinner_2 = uispinner(app.EEProjectAliNaghilooUIFigure);
            app.mSpinner_2.Limits = [1 1];
            app.mSpinner_2.ValueChangedFcn = createCallbackFcn(app, @mSpinner_2ValueChanged, true);
            app.mSpinner_2.Value = 1;
            app.EditField_Range = uieditfield(app.EEProjectAliNaghilooUIFigure, 'numeric');
            app.EditField_Range.ValueChangedFcn = createCallbackFcn(app, @EditField_RangeValueChanged, true);
            app.EditField_Range.Value = 50;
            app.EditField_Range_2 = uieditfield(app.EEProjectAliNaghilooUIFigure, 'numeric');
            app.EditField_Range_2.ValueChangedFcn = createCallbackFcn(app, @EditField_Range_2ValueChanged, true);
            app.EditField_Range_2.Value = 175;
            app.SensivityGraphClickButton = uibutton(app.EEProjectAliNaghilooUIFigure, 'push');
            app.SensivityGraphClickButton.ButtonPushedFcn = createCallbackFcn(app, @SensivityGraphClickButtonPushed, true);
            app.SensivityGraphClickButton.Text = {'Sensivity Graph'; 'Click!'};
            % Show the figure after all components are created
            app.EEProjectAliNaghilooUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEProjectAliNaghilooUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEProjectAliNaghilooUIFigure)
        end
    end
end